classdef gui_app3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ToolsMenu                     matlab.ui.container.Menu
        UploadImageMenu               matlab.ui.container.Menu
        ScreenshotMenu                matlab.ui.container.Menu
        ModeSwitchMenu                matlab.ui.container.Menu
        BeginnerMenu                  matlab.ui.container.Menu
        ExpertMenu                    matlab.ui.container.Menu
        ResetMenu                     matlab.ui.container.Menu
        Toolbar                       matlab.ui.container.Toolbar
        PushTool2                     matlab.ui.container.toolbar.PushTool
        PushTool                      matlab.ui.container.toolbar.PushTool
        PushTool3                     matlab.ui.container.toolbar.PushTool
        PushTool4                     matlab.ui.container.toolbar.PushTool
        PushTool6                     matlab.ui.container.toolbar.PushTool
        PushTool5                     matlab.ui.container.toolbar.PushTool
        YSlider                       matlab.ui.control.Slider
        YSliderLabel                  matlab.ui.control.Label
        XSlider                       matlab.ui.control.Slider
        XSliderLabel                  matlab.ui.control.Label
        ZSlider                       matlab.ui.control.Slider
        ZSliderLabel                  matlab.ui.control.Label
        NumberofForegroundEditField   matlab.ui.control.NumericEditField
        NumberofForegroundLabel       matlab.ui.control.Label
        OKButton                      matlab.ui.control.Button
        GUIInstructionsTextArea       matlab.ui.control.TextArea
        GUIInstructionsTextAreaLabel  matlab.ui.control.Label
        ModeSwitchButton              matlab.ui.control.StateButton
        TippsTextArea                 matlab.ui.control.TextArea
        TippsTextAreaLabel            matlab.ui.control.Label
        StepsPanel                    matlab.ui.container.Panel
        GridLayout                    matlab.ui.container.GridLayout
        Step6AnimationButton          matlab.ui.control.Button
        Step5ScreenshotButton         matlab.ui.control.Button
        Step43DModelButton            matlab.ui.control.Button
        Step3SelectionButton          matlab.ui.control.Button
        Step2ForegroundButton         matlab.ui.control.Button
        Step1LoadPictureButton        matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
        ContextMenu                   matlab.ui.container.ContextMenu
        ResetMenu_2                   matlab.ui.container.Menu
        ContextMenu2                  matlab.ui.container.ContextMenu
        ResetMenu_3                   matlab.ui.container.Menu
        ContextMenu3                  matlab.ui.container.ContextMenu
        ResetMenu_4                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        picture; % Description
        points % Description
        picture2 % Description
        estimatedVertex % Description
        camera_position % Description
        i;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ModeSwitchButton
        function ModeSwitchButtonValueChanged2(app, event)
            value = app.ModeSwitchButton.Value;
            switch value
                case 0             
                    app.StepsPanel.Visible ="on";
                    app.TippsTextArea.Visible ="on";
                    app.TippsTextAreaLabel.Visible ="on";
                    app.Toolbar.Visible="off";
                    app.UIAxes.Position = [229 109 929 564];
                    %app.UIAxes.Position = [229 130 929 564];
                case 1
                    app.StepsPanel.Visible="off";
                    app.TippsTextArea.Visible ="off";
                    app.TippsTextAreaLabel.Visible="off";
                    app.Toolbar.Visible="on";
                    app.UIAxes.Position = [17 61 1141 612];
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.GUIInstructionsTextArea.Visible = "off";
            app.GUIInstructionsTextAreaLabel.Visible = "off";
            app.OKButton.Visible="off";
            app.TippsTextArea.Value=["Now pay attention to the left side about Steps List."
                      "" 
            "Firstly, click the Step1 Button to choose the picture that you want deal with."
                      ];
        end

        % Button pushed function: Step1LoadPictureButton
        function Step1LoadPictureButtonPushed(app, event)
            
            app.UIAxes.Position = [229 109 929 564];
            % app.UIAxes.CameraPositionMode ="auto";
            % app.UIAxes.CameraTargetMode ="auto";
            % app.UIAxes.Projection="orthographic";
            app.picture=[];
            f=[];
            p=[];
            [f ,p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            app.picture = imread(fname);
            
            
            imshow(app.picture,'Parent',app.UIAxes);
            app.UIAxes.XLimMode ="auto";
            app.UIAxes.YLimMode ="auto";
            app.UIAxes.Position = [229 109 929 564];
            app.TippsTextArea.Value=[
                      "Then if there are any Foreground need to choose, fill the number on the right side and click the Step 2 Button."
                      "" 
            "If not, click the Step 3 Button directly to select the Vanish Point and Background of the picture."
            "" 
            "If you want to change the uploaded image, right click the Step 1 Button to reset this step and left click this Button to select new picture."
                      ];

           app.NumberofForegroundEditField.Visible="on";
           app.NumberofForegroundLabel.Visible="on";
        end

        % Callback function: PushTool2
        function PushTool2Clicked(app, event)
              app.UIAxes.Position = [229 130 929 564];
            % app.UIAxes.CameraPositionMode ="auto";
            % app.UIAxes.CameraTargetMode ="auto";
            % app.UIAxes.Projection="orthographic";
            app.picture=[];
            f=[];
            p=[];
            [f ,p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            app.picture = imread(fname);
            
            imshow(app.picture,'Parent',app.UIAxes);
            app.UIAxes.Position = [229 130 929 564];
            

           app.NumberofForegroundEditField.Visible="on";
           app.NumberofForegroundLabel.Visible="on";
        end

        % Menu selected function: UploadImageMenu
        function UploadImageMenuSelected(app, event)
              app.UIAxes.Position = [229 109 929 564];
            % app.UIAxes.CameraPositionMode ="auto";
            % app.UIAxes.CameraTargetMode ="auto";
            % app.UIAxes.Projection="orthographic";
            app.picture=[];
            f=[];
            p=[];
            [f ,p]= uigetfile({'*.jpg;*.png','Image Files'});
            fname =[p f];
            app.picture = imread(fname);
            
            imshow(app.picture,'Parent',app.UIAxes);
            app.UIAxes.Position = [229 109 929 564];
            
           app.NumberofForegroundEditField.Visible="on";
           app.NumberofForegroundLabel.Visible="on";
        end

        % Button pushed function: Step3SelectionButton
        function Step3SelectionButtonPushed(app, event)
            app.NumberofForegroundLabel.Visible="off";
            app.NumberofForegroundEditField.Visible="off";
            
            app.TippsTextArea.Value=["Now it's the Selection process."
                      "" 
            "Use the mouse to move the vanish point and double click to confirm, also for the background."
                    "" 
            "The blue lines show the five region of the picture.  Then click the Step 4 Button to show the 3D Model."  
            "" 
            "If you're not satisfied with result, right click the Step 3 Button to reset this step and left click this Button to repeat again."];
           
            % app.points = Selection(app.picture,app.UIAxes);

            if isempty(app.picture2)
                app.points = Selection(app.picture,app.UIAxes);

            else
                 app.points = Selection(app.picture2,app.UIAxes);

            end
            app.UIAxes.XLimMode ="auto";
            app.UIAxes.YLimMode ="auto";
        end

        % Button pushed function: Step2ForegroundButton
        function Step2ForegroundButtonPushed(app, event)
            
            app.TippsTextArea.Value=["Now it's the Foreground process."
                      "" 
            "Click the point one by one around the Foreground on the figure."
                    "" 
            "When finished cutting, double click to confirm.  Then click the Step 3 Button."
            "" 
            "If you're not satisfied with result, right click the Step 2 Button to reset this step, fill the number and left click this Button to repeat again."];
           
            n = app.NumberofForegroundEditField.Value;
            patchsize = 9;
            fillorder = 'gradient';
            app.UIAxes.XLimMode ="auto";
            app.UIAxes.YLimMode ="auto";
            [fg2D, foreground, app.picture2] = SegmentImage(n, patchsize, fillorder,app.picture,app.UIAxes);
            
            imshow(app.picture2,'Parent',app.UIAxes);
            
        end

        % Callback function: PushTool
        function PushToolClicked(app, event)
            n = app.NumberofForegroundEditField.Value;
            patchsize = 9;
            fillorder = 'gradient';
            [fg2D, foreground, app.picture2] = SegmentImage(n, patchsize, fillorder,app.picture,app.UIAxes);
            app.UIAxes.XLimMode ="auto";
            app.UIAxes.YLimMode ="auto";
         
            imshow(app.picture2,'Parent',app.UIAxes);

        end

        % Menu selected function: BeginnerMenu
        function BeginnerMenuSelected(app, event)
                    app.StepsPanel.Visible ="on";
                    app.TippsTextArea.Visible ="on";
                    app.TippsTextAreaLabel.Visible ="on";
                    app.Toolbar.Visible="off";
                    app.UIAxes.Position = [229 109 929 564];
        end

        % Menu selected function: ExpertMenu
        function ExpertMenuSelected(app, event)
                    app.StepsPanel.Visible="off";
                    app.TippsTextArea.Visible ="off";
                    app.TippsTextAreaLabel.Visible="off";
                    app.Toolbar.Visible="on";
                    app.UIAxes.Position = [17 61 1141 612];
        end

        % Callback function: PushTool3
        function PushTool3Clicked(app, event)
            app.NumberofForegroundLabel.Visible="off";
            app.NumberofForegroundEditField.Visible="off"; 
            app.UIAxes.XLimMode ="auto";
            app.UIAxes.YLimMode ="auto";
            if isempty(app.picture2)
                app.points = Selection(app.picture,app.UIAxes);

            else
                 app.points = Selection(app.picture2,app.UIAxes);

            end
        end

        % Button pushed function: Step43DModelButton
        function Step43DModelButtonPushed(app, event)
            app.TippsTextArea.Value=["Now it can show the 3D Model for this picture."
             "" 
             "The x controls left and right, y control up and down, z controls zoom in and out."
             "" 
             "If you want to screenshot, then click the Step 5 Button."
             "Or you can click the Step 6 Button to show the Animation."];
            cla(app.UIAxes);
            view(app.UIAxes,3); %3D version
            set(app.UIAxes, 'XDir', 'reverse');
            app.UIAxes.Position = [270 109 929 564];
            clear app.UIAxes.CameraPosition ;
            clear  app.UIAxes.CameraTarget ;
           
            if isempty(app.picture2)
                [virtual_output, app.estimatedVertex] = Build3DModel_new(app.points, app.picture);
                % subimages = image_mask(img, app.points);
                transformed_subimages= apply_perspective_transforms(app.picture, app.points);
                render3DScene(transformed_subimages, app.estimatedVertex,app.UIAxes);

            else
                 [virtual_output, app.estimatedVertex] = Build3DModel_new(app.points, app.picture2);
                % subimages = image_mask(img, app.points);
                transformed_subimages= apply_perspective_transforms(app.picture2, app.points);
                render3DScene(transformed_subimages, app.estimatedVertex,app.UIAxes);
            end
            
            center = [(app.estimatedVertex(1,2)+app.estimatedVertex(1,3))/2,(app.estimatedVertex(2,2)+app.estimatedVertex(2,8))/2];
            
            
             x_bound = abs((app.estimatedVertex(1,2)-app.estimatedVertex(1,3))/2);
             y_bound = abs((app.estimatedVertex(2,8)-app.estimatedVertex(2,2))/2);
             z_lbound = min(app.estimatedVertex(3,4),app.estimatedVertex(3,6))/3;
             z_hbound = app.estimatedVertex(3,1)*0.8;
             
     
            xmin=center(1)-x_bound;
            xmax=center(1)+x_bound;
            ymin=center(2)-y_bound;
            ymax=center(2)+y_bound;
            zmin=z_hbound;
            zmax=z_lbound;
            
            xstep=(xmax-xmin)/20;
            ystep=(xmax-xmin)/20;
            zstep=(xmax-xmin)/20;


            app.XSlider.Limits=[xmin,xmax];
            app.YSlider.Limits=[ymin,ymax];
            app.ZSlider.Limits=[zmin,zmax];
            
             
            app.XSlider.MajorTicksMode = 'manual';  
            app.XSlider.MajorTicks = xmin:xstep:xmax; 
          
            app.YSlider.MajorTicksMode = 'manual';  
            app.YSlider.MajorTicks = ymin:ystep:ymax;  
           
            
            app.ZSlider.MajorTicksMode = 'manual';  
            app.ZSlider.MajorTicks = zmin:zstep:zmax;  


            app.XSlider.Value = center(1);
            app.YSlider.Value = center(2);
            app.ZSlider.Value = zmax;
          
            app.camera_position= [center(1),center(2),app.ZSlider.Value];
            
            target_position = [app.XSlider.Value,app.YSlider.Value,app.estimatedVertex(3,1)];
            
            Show3DModel(app.UIAxes,target_position,app.estimatedVertex(:,1),app.camera_position)
           
            app.UIAxes.Position = [270 109 929 564];
            app.XSlider.Visible="on";
            app.XSliderLabel.Visible="on";
            app.YSlider.Visible="on";
            app.YSliderLabel.Visible="on";
            app.ZSlider.Visible="on";
            app.ZSliderLabel.Visible="on";
            % app.UIAxes.XLimMode ="auto";
            % app.UIAxes.YLimMode ="auto";
            % app.UIAxes.ZLimMode ="auto";
            app.i=0;
            
            
        end

        % Callback function: PushTool4
        function PushTool4Clicked(app, event)
            cla(app.UIAxes);
            view(app.UIAxes,3); %3D version
            app.UIAxes.ZLimMode ="auto";
            set(app.UIAxes, 'XDir', 'reverse');
            app.UIAxes.Position = [229 130 929 564];
            app.UIAxes.CameraPosition =[];
            app.UIAxes.CameraTarget = [];
            if isempty(app.picture2)
                [virtual_output, app.estimatedVertex] = Build3DModel_new(app.points, app.picture);
                % subimages = image_mask(img, app.points);
                transformed_subimages= apply_perspective_transforms(app.picture, app.points);
                render3DScene(transformed_subimages, app.estimatedVertex,app.UIAxes);

            else
                 [virtual_output, app.estimatedVertex] = Build3DModel_new(app.points, app.picture2);
                % subimages = image_mask(img, app.points);
                transformed_subimages= apply_perspective_transforms(app.picture2, app.points);
                render3DScene(transformed_subimages, app.estimatedVertex,app.UIAxes);
            end
            
            center = [(app.estimatedVertex(1,2)+app.estimatedVertex(1,3))/2,(app.estimatedVertex(2,2)+app.estimatedVertex(2,8))/2];
            
            
             x_bound = abs((app.estimatedVertex(1,2)-app.estimatedVertex(1,3))/2);
             y_bound = abs((app.estimatedVertex(2,8)-app.estimatedVertex(2,2))/2);
             z_lbound = min(app.estimatedVertex(3,4),app.estimatedVertex(3,6))/3;
             z_hbound = app.estimatedVertex(3,1)*0.8;
             
     
            xmin=center(1)-x_bound;
            xmax=center(1)+x_bound;
            ymin=center(2)-y_bound;
            ymax=center(2)+y_bound;
            zmin=z_hbound;
            zmax=z_lbound;
            
            xstep=(xmax-xmin)/20;
            ystep=(xmax-xmin)/20;
            zstep=(xmax-xmin)/20;


            app.XSlider.Limits=[xmin,xmax];
            app.YSlider.Limits=[ymin,ymax];
            app.ZSlider.Limits=[zmin,zmax];
            
             
            app.XSlider.MajorTicksMode = 'manual';  
            app.XSlider.MajorTicks = xmin:xstep:xmax; 
          
            app.YSlider.MajorTicksMode = 'manual';  
            app.YSlider.MajorTicks = ymin:ystep:ymax;  
           
            
            app.ZSlider.MajorTicksMode = 'manual';  
            app.ZSlider.MajorTicks = zmin:zstep:zmax;  


            app.XSlider.Value = center(1);
            app.YSlider.Value = center(2);
            app.ZSlider.Value = zmax;
          
            app.camera_position= [center(1),center(2),app.ZSlider.Value];
            
            target_position = [app.XSlider.Value,app.YSlider.Value,app.estimatedVertex(3,1)];
            
            Show3DModel(app.UIAxes,target_position,app.estimatedVertex(:,1),app.camera_position)
            
            app.UIAxes.XLimMode ="auto";
            app.UIAxes.YLimMode ="auto";
            app.XSlider.Visible="on";
            app.XSliderLabel.Visible="on";
            app.YSlider.Visible="on";
            app.YSliderLabel.Visible="on";
            app.ZSlider.Visible="on";
            app.ZSliderLabel.Visible="on";

            app.i=0;
            app.UIAxes.ZLimMode ="auto";
        
        end

        % Context menu opening function: ContextMenu
        function ContextMenuOpening(app, event)
            %reset: load picture again
            cla(app.UIAxes);
        end

        % Context menu opening function: ContextMenu2
        function ContextMenu2Opening(app, event)
            cla(app.UIAxes);
            imshow(app.picture,'Parent',app.UIAxes);
           % delete
        end

        % Context menu opening function: ContextMenu3
        function ContextMenu3Opening(app, event)
            cla(app.UIAxes);
            if isempty(app.picture2)
                imshow(app.picture,'Parent',app.UIAxes);
            else
                imshow(app.picture2,'Parent',app.UIAxes);
            end
        end

        % Menu selected function: ResetMenu
        function ResetMenuSelected(app, event)
            app.points=[];
            app.camera_position=[];
            app.estimatedVertex=[];
            app.i=0;
            app.picture2=[];
            app.picture=[];
            cla(app.UIAxes);
            app.UIAxes.reset;
            app.UIAxes.Visible = "off";
            app.UIAxes.Position = [229 130 929 564];
            app.XSlider.Visible="off";
            app.XSliderLabel.Visible="off";
            app.YSlider.Visible="off";
            app.YSliderLabel.Visible="off";
            app.ZSlider.Visible="off";
            app.ZSliderLabel.Visible="off";
            app.StepsPanel.Visible="on";
            app.TippsTextArea.Visible ="on";
            app.TippsTextAreaLabel.Visible="on";
            app.Toolbar.Visible="off";
        end

        % Value changed function: XSlider
        function XSliderValueChanged(app, event)
            
            value = app.XSlider.Value;

            target_position = [value,app.YSlider.Value,app.ZSlider.Value];
            Show3DModel(app.UIAxes,target_position,app.estimatedVertex(:,1),app.camera_position);
        end

        % Value changed function: ZSlider
        function ZSliderValueChanged(app, event)
            value = app.ZSlider.Value;
            app.camera_position(3)=value;
            target_position = [app.XSlider.Value,app.YSlider.Value,value];
            Show3DModel(app.UIAxes,target_position,app.estimatedVertex(:,1),app.camera_position);
        end

        % Value changed function: YSlider
        function YSliderValueChanged(app, event)
            value = app.YSlider.Value;
            target_position = [app.XSlider.Value,value,app.ZSlider.Value];
            Show3DModel(app.UIAxes,target_position,app.estimatedVertex(:,1),app.camera_position);
        end

        % Button pushed function: Step5ScreenshotButton
        function Step5ScreenshotButtonPushed(app, event)
           
           app.i=app.i+1;
           current_folder = pwd;
           folder_path='/Screenshot';
           
           new_folder_path = fullfile(current_folder, folder_path);
           
           if ~exist(new_folder_path , 'dir')
                mkdir(new_folder_path );
           end
           
           filename = ['Image_' num2str(app.i) '.png'];
           save_path = fullfile(new_folder_path, filename);

           exportgraphics(app.UIAxes, save_path);

           msgbox('The Screenshot has saved successfully!');
           
        end

        % Button pushed function: Step6AnimationButton
        function Step6AnimationButtonPushed(app, event)
            app.StepsPanel.Visible="off";
            app.TippsTextArea.Visible ="off";
            app.TippsTextAreaLabel.Visible="off";
            app.Toolbar.Visible="on";
            app.UIAxes.Position = [17 61 1141 612];
            
            Animation(app.UIAxes,app.estimatedVertex(:,1),app.estimatedVertex(:,2:13));


        end

        % Callback function: PushTool6
        function PushTool6Clicked(app, event)
            app.i=app.i+1;
           current_folder = pwd;
           folder_path='/Screenshot';
           
           new_folder_path = fullfile(current_folder, folder_path);
           
           if ~exist(new_folder_path , 'dir')
                mkdir(new_folder_path );
           end
           
           filename = ['Image_' num2str(app.i) '.png'];
           save_path = fullfile(new_folder_path, filename);

           exportgraphics(app.UIAxes, save_path);

           msgbox('The Screenshot has saved successfully!');
        end

        % Callback function: PushTool5
        function PushTool5Clicked(app, event)
            app.StepsPanel.Visible="off";
            app.TippsTextArea.Visible ="off";
            app.TippsTextAreaLabel.Visible="off";
            app.Toolbar.Visible="on";
            app.UIAxes.Position = [17 61 1141 612];
            
            Animation(app.UIAxes,app.estimatedVertex(:,1),app.estimatedVertex(:,2:13));
            
        end

        % Menu selected function: ScreenshotMenu
        function ScreenshotMenuSelected(app, event)
            app.i=app.i+1;
           current_folder = pwd;
           folder_path='/Screenshot';
           
           new_folder_path = fullfile(current_folder, folder_path);
           
           if ~exist(new_folder_path , 'dir')
                mkdir(new_folder_path );
           end
           
           filename = ['Image_' num2str(app.i) '.png'];
           save_path = fullfile(new_folder_path, filename);

           exportgraphics(app.UIAxes, save_path);

           msgbox('The Screenshot has saved successfully!');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            colormap(app.UIFigure, 'colorcube');
            app.UIFigure.Name = 'MATLAB App';
            app.ToolsMenu = uimenu(app.UIFigure);
            app.ToolsMenu.Text = 'Tools';
            app.UploadImageMenu = uimenu(app.ToolsMenu);
            app.UploadImageMenu.MenuSelectedFcn = createCallbackFcn(app, @UploadImageMenuSelected, true);
            app.UploadImageMenu.Text = 'Upload Image';
            app.ScreenshotMenu = uimenu(app.ToolsMenu);
            app.ScreenshotMenu.MenuSelectedFcn = createCallbackFcn(app, @ScreenshotMenuSelected, true);
            app.ScreenshotMenu.Text = 'Screenshot';
            app.ModeSwitchMenu = uimenu(app.ToolsMenu);
            app.ModeSwitchMenu.Text = 'Mode Switch';
            app.BeginnerMenu = uimenu(app.ModeSwitchMenu);
            app.BeginnerMenu.MenuSelectedFcn = createCallbackFcn(app, @BeginnerMenuSelected, true);
            app.BeginnerMenu.Text = 'Beginner';
            app.ExpertMenu = uimenu(app.ModeSwitchMenu);
            app.ExpertMenu.MenuSelectedFcn = createCallbackFcn(app, @ExpertMenuSelected, true);
            app.ExpertMenu.Text = 'Expert';
            app.ResetMenu = uimenu(app.ToolsMenu);
            app.ResetMenu.MenuSelectedFcn = createCallbackFcn(app, @ResetMenuSelected, true);
            app.ResetMenu.Text = 'Reset';
            app.Toolbar = uitoolbar(app.UIFigure);
            app.Toolbar.Visible = 'off';
            app.PushTool2 = uipushtool(app.Toolbar);
            app.PushTool2.ClickedCallback = createCallbackFcn(app, @PushTool2Clicked, true);
            app.PushTool2.Icon = fullfile(pathToMLAPP, 'icon', 'upload.png');
            app.PushTool = uipushtool(app.Toolbar);
            app.PushTool.ClickedCallback = createCallbackFcn(app, @PushToolClicked, true);
            app.PushTool.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mat-wallpaper-0072bd.png');
            app.PushTool3 = uipushtool(app.Toolbar);
            app.PushTool3.ClickedCallback = createCallbackFcn(app, @PushTool3Clicked, true);
            app.PushTool3.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-stateflow-chart-0072bd.png');
            app.PushTool4 = uipushtool(app.Toolbar);
            app.PushTool4.ClickedCallback = createCallbackFcn(app, @PushTool4Clicked, true);
            app.PushTool4.Icon = fullfile(pathToMLAPP, 'icon', 'icon-fa-dropbox-0072bd.png');
            app.PushTool6 = uipushtool(app.Toolbar);
            app.PushTool6.ClickedCallback = createCallbackFcn(app, @PushTool6Clicked, true);
            app.PushTool6.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-photo-0072bd.png');
            app.PushTool5 = uipushtool(app.Toolbar);
            app.PushTool5.ClickedCallback = createCallbackFcn(app, @PushTool5Clicked, true);
            app.PushTool5.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-media-video-0072bd.png');
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes.Visible = 'off';
            app.StepsPanel = uipanel(app.UIFigure);
            app.StepsPanel.Title = 'Steps';
            app.GridLayout = uigridlayout(app.StepsPanel);
            app.Step1LoadPictureButton = uibutton(app.GridLayout, 'push');
            app.Step1LoadPictureButton.ButtonPushedFcn = createCallbackFcn(app, @Step1LoadPictureButtonPushed, true);
            app.Step1LoadPictureButton.Icon = fullfile(pathToMLAPP, 'icon', 'uploadw.png');
            app.Step1LoadPictureButton.Text = {'Step 1: '; 'Load Picture'};
            app.Step2ForegroundButton = uibutton(app.GridLayout, 'push');
            app.Step2ForegroundButton.ButtonPushedFcn = createCallbackFcn(app, @Step2ForegroundButtonPushed, true);
            app.Step2ForegroundButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mat-wallpaper-ffffff-71.png');
            app.Step2ForegroundButton.Text = {'Step 2:'; 'Foreground'};
            app.Step3SelectionButton = uibutton(app.GridLayout, 'push');
            app.Step3SelectionButton.ButtonPushedFcn = createCallbackFcn(app, @Step3SelectionButtonPushed, true);
            app.Step3SelectionButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-stateflow-chart-ffffff-71.png');
            app.Step3SelectionButton.Text = {'Step 3:'; 'Selection'};
            app.Step43DModelButton = uibutton(app.GridLayout, 'push');
            app.Step43DModelButton.ButtonPushedFcn = createCallbackFcn(app, @Step43DModelButtonPushed, true);
            app.Step43DModelButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-fa-dropbox-ffffff-71.png');
            app.Step43DModelButton.Text = {'Step 4:'; '3D Model'};
            app.Step5ScreenshotButton = uibutton(app.GridLayout, 'push');
            app.Step5ScreenshotButton.ButtonPushedFcn = createCallbackFcn(app, @Step5ScreenshotButtonPushed, true);
            app.Step5ScreenshotButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-photo-ffffff-71.png');
            app.Step5ScreenshotButton.Text = {'Step 5:'; 'Screenshot'};
            app.Step6AnimationButton = uibutton(app.GridLayout, 'push');
            app.Step6AnimationButton.ButtonPushedFcn = createCallbackFcn(app, @Step6AnimationButtonPushed, true);
            app.Step6AnimationButton.Icon = fullfile(pathToMLAPP, 'icon', 'icon-mw-media-video-ffffff-71.png');
            app.Step6AnimationButton.Text = {'Step 6:'; 'Animation'};
            app.TippsTextAreaLabel = uilabel(app.UIFigure);
            app.TippsTextAreaLabel.Text = 'Tipps';
            app.TippsTextArea = uitextarea(app.UIFigure);
            app.TippsTextArea.Value = {'Welcome to the Tour Into the Picture!'; ''; 'Now read the GUI Instructions carefully and when finished click the OK Button.'; ''; 'Enjoy your Tour!'};
            app.ModeSwitchButton = uibutton(app.UIFigure, 'state');
            app.ModeSwitchButton.ValueChangedFcn = createCallbackFcn(app, @ModeSwitchButtonValueChanged2, true);
            app.ModeSwitchButton.Text = 'Mode Switch';
            app.GUIInstructionsTextAreaLabel = uilabel(app.UIFigure);
            app.GUIInstructionsTextAreaLabel.Text = {'GUI '; 'Instructions'};
            app.GUIInstructionsTextArea = uitextarea(app.UIFigure);
            app.GUIInstructionsTextArea.Value = {'This interface offers two modes: Beginner Mode (default) and Expert Mode. Switch modes using the button at the bottom.'; ''; '1.Beginner Mode'; 'Task Bar (Left): Follow the sequential tasks listed.'; ''; 'Image Display (Center): View related images for each task.'; ''; 'Tip Area (Bottom): Get step-by-step guidance and tipps.'; ''; ''; '2.Expert Mode'; 'Icon Toolbar (Below Menu Bar): Use the tools in a left-to-right sequence.'; ''; 'Designed for experienced users with a streamlined layout.'; ''; ''; '3.Menu Bar (Top)'; 'Contains commonly used tools, including a reset tool that restart all step from beginning or switching images.'; ''; ''; '4.Mode Switching'; 'Use the bottom button to switch between Beginner Mode and Expert Mode.'; ''; ''; ''; 'Enjoy using the GUI! '};
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.NumberofForegroundLabel = uilabel(app.UIFigure);
            app.NumberofForegroundLabel.Visible = 'off';
            app.NumberofForegroundLabel.Text = 'Number of Foreground:';
            app.NumberofForegroundEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofForegroundEditField.Visible = 'off';
            app.NumberofForegroundEditField.Value = 1;
            app.ZSliderLabel = uilabel(app.UIFigure);
            app.ZSliderLabel.Visible = 'off';
            app.ZSliderLabel.Text = 'Z';
            app.ZSlider = uislider(app.UIFigure);
            app.ZSlider.Limits = [-100 500];
            app.ZSlider.MajorTicks = [];
            app.ZSlider.MajorTickLabels = {''};
            app.ZSlider.ValueChangedFcn = createCallbackFcn(app, @ZSliderValueChanged, true);
            app.ZSlider.MinorTicks = [];
            app.ZSlider.Visible = 'off';
            app.XSliderLabel = uilabel(app.UIFigure);
            app.XSliderLabel.Visible = 'off';
            app.XSliderLabel.Text = 'X';
            app.XSlider = uislider(app.UIFigure);
            app.XSlider.Limits = [-100 500];
            app.XSlider.MajorTicks = [];
            app.XSlider.MajorTickLabels = {''};
            app.XSlider.ValueChangedFcn = createCallbackFcn(app, @XSliderValueChanged, true);
            app.XSlider.MinorTicks = [];
            app.XSlider.Visible = 'off';
            app.YSliderLabel = uilabel(app.UIFigure);
            app.YSliderLabel.Visible = 'off';
            app.YSliderLabel.Text = 'Y';
            app.YSlider = uislider(app.UIFigure);
            app.YSlider.MajorTicks = [];
            app.YSlider.MajorTickLabels = {''};
            app.YSlider.ValueChangedFcn = createCallbackFcn(app, @YSliderValueChanged, true);
            app.YSlider.MinorTicks = [];
            app.YSlider.Visible = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ContextMenu.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenuOpening, true);
            app.ResetMenu_2 = uimenu(app.ContextMenu);
            app.ResetMenu_2.Text = 'Reset';
            % Assign app.ContextMenu
            app.Step1LoadPictureButton.ContextMenu = app.ContextMenu;
            app.ContextMenu2 = uicontextmenu(app.UIFigure);
            app.ContextMenu2.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu2Opening, true);
            app.ResetMenu_3 = uimenu(app.ContextMenu2);
            app.ResetMenu_3.Text = 'Reset';
            % Assign app.ContextMenu2
            app.Step2ForegroundButton.ContextMenu = app.ContextMenu2;
            app.ContextMenu3 = uicontextmenu(app.UIFigure);
            app.ContextMenu3.ContextMenuOpeningFcn = createCallbackFcn(app, @ContextMenu3Opening, true);
            app.ResetMenu_4 = uimenu(app.ContextMenu3);
            app.ResetMenu_4.Text = 'Reset';
            % Assign app.ContextMenu3
            app.Step3SelectionButton.ContextMenu = app.ContextMenu3;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui_app3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end